classdef Orange < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        axis1               matlab.ui.control.UIAxes
        axisLogo            matlab.ui.control.UIAxes
        EventDropDownLabel  matlab.ui.control.Label
        EventDropDown       matlab.ui.control.DropDown
        SmoothSwitchLabel   matlab.ui.control.Label
        SmoothSwitch        matlab.ui.control.RockerSwitch
        OrangeViewerglennjocherultralyticscomLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        G=[]; % Description
    end
    
    methods (Access = public)
        function G = loadnewfile(app)
            G = fcnloadtextfile('',5E5,3);  if isempty(G); return; end
            G.E=G.x(:,2);  G.unixTime=G.double;  G.events=fcnunique(G.E);  %A.x=int16(A.x);
            G.ve = G.events;  ne=numel(G.ve);
            G.ne=ne;
            G.promptFlag=true;
            
            pfile = 'pedestals.exp_0099_run_0156.glenn.mat';
            load(pfile); %load pedestals
            G.pedestals=muvb;
            G.pedestalOutliers=false(size(pedestalOutliers));
            G.SRCCHi = MTCpixelID2SRCCH(1:1536);
            load MTCoffsets.mat; G.offsets=map; %pixel offsets (1-1536);
            H = design(fdesign.lowpass('Fp,Fst',0.01,.2), 'equiripple');  % Design an FIR equiripple filter
            H.Arithmetic = class(single(1));  G.filter = H;
            assignin('base','G',G)
            
            dt = max(G.unixTime)/1E6-minnonzero(G.unixTime)/1E6; %(s)
            fprintf('\n%g events over %.1f s (%.1f Hz)\n%g missing unix times',G.ne,dt,G.ne/(dt),mean(G.unixTime==0));
            [~,~,~,n]=fcnunique(G.x(:,2));  fprintf(', %g missing windows\n',sum(512-n));
            
            %app.EventSpinner.Limits=double([min(G.ve) max(G.ve)]); app.EventSpinner.Value=min(G.ve);
            app.EventDropDown.Items=cellfun(@num2str,num2cell(G.ve),'UniformOutput',false);

            app.G=G;
            fcnplot1MTCevent(app); %view(0,90); axis normal xy tight on; grid on
        end
        
        function app = fcnplot1MTCevent(app)
            G=app.G;
            %enstr = num2str(app.EventSpinner.Value); %event number
            enstr = app.EventDropDown.Value; %event number
            en = eval(enstr);
            ei = find(G.ve==en);
            G.x=G.x(G.E==en,:);
            
            %PRUNE
            pid = NTCIRSDmap(G.x(:,3:6),'pixel');
            i = ~any(pid==[33 34 35 36 41 42 43 44],2);  %[~, i] = fcnpruninglist(mtcpid);
            %i=true(size(mtcpid));
            G.x = G.x(i,:);  pid=pid(i);  mtcpid = MTCSRCCH2pixelID(G.x(:,3:6));
            
            nw=32;  RW=G.x(:,7);  W=G.x(:,8);  Wa=W-RW+1;  Wa(W<RW)=Wa(W<RW)+nw;
            Vx=int16(G.x(:,10:73)');  nj=sum(i);
            
            %SUBTRACT PEDESTALS
            Vx = subtractpedestals(G.pedestals,G.pedestalOutliers,mtcpid,W,Vx);
            j=1:nj;
            Wa=Wa(j);  nw=max(Wa)-min(Wa)+1;  nj=numel(j);  pid=pid(j);  Vx=Vx(:,j);  %fwi(i)=min(Wa);
            
            V = zeros(nw*64,128,'int16');
            k = sub2ind([64 nw 128],ones(nj,1),Wa-min(Wa)+1,pid);  %DOUBLE BOOKEND
            
            V(k+(0:63)) = Vx';
            V=single(V); %xa(xa==0)=nan;
            
            %fig; pcolor(V); shading flat
            
            if strcmp(app.SmoothSwitch.Value,'On')
                V([1 2 129 130],:)=nan; %remove stitching spikes
                V=removeSpikes(V,10);
                V=removeSpikes(V,[5 3]);
                porch=nanporch(V,20);
                V=V-porch;
                
                V=filter(G.filter,V);
                %y=fcnsmooth(y(:),5);
                [stats, V]=geteventstats(app,G,V);
                stats = [stats porch(:)];
            else
                stats=zeros(128,6);
                stats(:,2) = max(V);
            end
            amplitude = stats(:,2);
            time = stats(:,4);
            V(end,:)=nan;  if nargout>0; return; end
            
            x=(1:size(V,1))';  ARRAY=NTCIRSDmap(1:128,'SRCCH');  ARRAY=ARRAY(:,6); colors={[255 105 0]/255,[255 204 0]/255};
            h = app.axis1; cla(h); hold(h,'on'); h.XLim=[1 256];
            for i=1:2
                y = V(:,ARRAY==i);
                if ei==1; y(:,end+1)=nan; end;  ny=size(y,2); %#ok<AGROW>
                if ny>0
                    xi = repmat(x,[1 ny]);
                    plot(h,xi(:),y(:),'Color',colors{i},'Display',sprintf('Array %g',i),'linewidth',.8);
                end
            end
            h.XTick=1:64:size(V,1);  h.XTickLabel=0:numel(h.XTick)-1;
            h.Title.String = sprintf('%s event %s',str_(G.filename),enstr);
            if ei==1; hl=legend(h,'show'); hl.Position(2)=.8; xyzlabel(h,'Time (window)','Amplitude (sample)','PMT'); end
            %grid(h,'on')
        end
        
        function [stats, x] = geteventstats(app,G,x)
            nanx=isnan(x);  i=all(nanx(end-63:end,:),1) & ~all(nanx,1);  x(end-63:end,i)=0;  %fix last window missing
            [j, a, w, t, integral, x, ppv] = fcnpulsewidth(single(x),[.45 .50 .55],[10 20000],[15 50],1500,'fraction');  k=t~=0;
            %APPLY CALIBRATIONS
            %a(j) = a(j)./G.offsets(j,2);
            %t(k) = t(k) - G.offsets(k,4);
            %integral(j) = integral(j)./G.offsets(j,5);
            stats = [j, a, w, t, integral, ppv]; %[triggered, amplitude, width, time, integral, prepulse]
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            h=app.axisLogo;
            imshow('splash1.png','Parent',h); 
            h.BackgroundColor=[1 1 1];
            app.axis1.BackgroundColor=[1 1 1];
            axis(h,'tight')
            loadnewfile(app);
        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            %value = app.EventDropDown.Value;
            fcnplot1MTCevent(app)
        end

        % Callback function
        function EventSpinnerValueChanging(app, event)
            %changingValue = event.Value;
            fcnplot1MTCevent(app)
        end

        % Value changed function: SmoothSwitch
        function SmoothSwitchValueChanged(app, event)
            %value = app.SmoothSwitch.Value;
            fcnplot1MTCevent(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.axis1 = uiaxes(app.UIFigure);
            title(app.axis1, 'Please Select a *.glenn File ...');
            xlabel(app.axis1, 'X');
            ylabel(app.axis1, 'Y');
            app.axisLogo = uiaxes(app.UIFigure);
            app.EventDropDownLabel = uilabel(app.UIFigure);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.UIFigure);
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.SmoothSwitchLabel = uilabel(app.UIFigure);
            app.SmoothSwitchLabel.Text = 'Smooth';
            app.SmoothSwitch = uiswitch(app.UIFigure, 'rocker');
            app.SmoothSwitch.ValueChangedFcn = createCallbackFcn(app, @SmoothSwitchValueChanged, true);
            app.OrangeViewerglennjocherultralyticscomLabel = uilabel(app.UIFigure);
            app.OrangeViewerglennjocherultralyticscomLabel.Text = {'Orange Viewer'; 'glenn.jocher@ultralytics.com'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Orange()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end